classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FigureGUI               matlab.ui.Figure                   % UI Figure
        UIAxes                  matlab.ui.control.UIAxes           % Title
        LabelListBox            matlab.ui.control.Label            % Distur...
        ListBox                 matlab.ui.control.ListBox          % Normal...
        AlphaSliderLabel        matlab.ui.control.Label            % α
        AlphaSlider             matlab.ui.control.Slider           % [0 1]
        BetaSliderLabel         matlab.ui.control.Label            % β
        BetaSlider              matlab.ui.control.Slider           % [0 100]
        Alpha3SliderLabel       matlab.ui.control.Label            % α3
        Alpha3Slider            matlab.ui.control.Slider           % [5.000...
        Alpha5SliderLabel       matlab.ui.control.Label            % α5
        Alpha5Slider            matlab.ui.control.Slider           % [5.000...
        Alpha7SliderLabel       matlab.ui.control.Label            % α7
        Alpha7Slider            matlab.ui.control.Slider           % [5.000...
        t1Label                 matlab.ui.control.Label            % t1
        t1                      matlab.ui.control.NumericEditField % [0 1000]
        t2Label                 matlab.ui.control.Label            % t2
        t2                      matlab.ui.control.NumericEditField % [0 1000]
        NumOfCyclesLabel        matlab.ui.control.Label            % Number
        NumOfCycles             matlab.ui.control.NumericEditField % [1 50]
        Frequency               matlab.ui.container.ButtonGroup    % Frequency
        f50                     matlab.ui.control.RadioButton      % 50 Hz
        f60                     matlab.ui.control.RadioButton      % 60 Hz
        TimeDiff                matlab.ui.control.TextArea         % The ch...
        TauSliderLabel          matlab.ui.control.Label            % τ
        TauSlider               matlab.ui.control.Slider           % [8 40]
        FnSliderLabel           matlab.ui.control.Label            % Fn
        FnSlider                matlab.ui.control.Slider           % [300 900]
        KSliderLabel            matlab.ui.control.Label            % K
        KSlider                 matlab.ui.control.Slider           % [1.000...
        AlphaFlickerSliderLabel matlab.ui.control.Label            % αf
        AlphaFlickerSlider      matlab.ui.control.Slider           % [1.000...
        NoiseSliderLabel        matlab.ui.control.Label            % SNR
        NoiseSlider             matlab.ui.control.Slider           % [20 50]
        Noise                   matlab.ui.container.ButtonGroup    % Noise
        NoNoise                 matlab.ui.control.RadioButton      % No noise
        AWGN                    matlab.ui.control.RadioButton      % AWGN
        CreateDataBase          matlab.ui.control.Button           % CREATE
        TimesTable              matlab.ui.control.Button           % Times ...
        LabelTextArea           matlab.ui.control.Label            % Text Area
        Cover1                  matlab.ui.control.TextArea        
    end

    
    properties (Access = public)
        
        % Time Parametes:
        t_start = 0;
        t_end ; % TODO : Chosen by User/Random
        dt = 0.0003125; %dt = 1./Fsample % TODO : ASK RAM Chosen by User/Random
        t;
        
        f = 50; % [Hz] 
        Tcycle;
        NumofTcycles = 10;
        
        % The current signal :
        signal;
        noisy_signal;
        signal_label = 'Normal';
        
        % Exporting signals :
        field1 = 'labels';
        field2 = 'signals';
        DataBase; %Defined in start-up callback
        
        DBDialogBoxExist = 0;
        
        % Guided tour in th app (for first use):
        GuideOrNot_ = 'No';
        InstructionsNum = 1;
        FirstUse = true;
        
    end
    
    properties (Access = private)
        DBDialogBox % Description
  
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FigureGUI.Name ='Power Quality Signals GUI';
            
            %close ALL FORCE;% manage to close but also the wanted istance
            %close all;
            %clc;
            
            defaultOffVisibality(app);
            
            app.DataBase = struct(app.field1,[],app.field2,[]);% = {};
            
            app.Tcycle =1./(app.f); % [sec]
            app.t_end = app.NumofTcycles * app.Tcycle;
            app.t = [app.t_start : app.dt : app.t_end];
            
            % axis([0 10*pi -1.5 1.5]);% TODO : let the user set boundaries?
            
            %Deafultic signals, times:
            app.signal = sin(2*pi*app.f*app.t); 
            app.noisy_signal = awgn(app.signal,0); 
            app.t1.Value = 90; %[ms]
            app.t2.Value = 110; %[ms]
            
            % Users Instructions :
            app.GuideOrNot_ = GuideOrNot();
            if strcmp(app.GuideOrNot_,'No')
                app.Noise.Visible = 'on';
                app.NoiseSlider.Visible = 'on'; app.NoiseSliderLabel.Visible = 'on';
                app.Frequency.Visible = 'on';
                app.NumOfCycles.Visible = 'on'; app.NumOfCyclesLabel.Visible = 'on';
                app.CreateDataBase.Visible='on';
                app.UIAxes.Visible = 'on';
                PlotSignal(app);
            else
                GuiManual(app);
                PlotSignal(app);
            end
            
            app.Cover1.Visible = 'off';
            
        end

        % ListBox value changed function
        function DisListBox(app)
            app.signal_label = app.ListBox.Value;
            app.UIAxes.Title.String = app.signal_label;%Plotting
            
            % Guided tour msgs are being called when diff signals are chosen:
            if app.InstructionsNum>=2 && app.InstructionsNum<=5
                GuiManual(app);
            end
            
            %Sets parameter's range of each disturb :
            SetParametersRange( app ); 
            
            % Slideres visibality :
            if ~app.DBDialogBoxExist %DB app is not open
                SlidersVisibilty(app);
            else %DB app is open
                if strcmp(app.DBDialogBox.TabGroup.SelectedTab.Title,'Current GUI`s signal')
                    SlidersVisibilty( app  );
                else
                    defaultOffVisibality(app);
                end
                app.DBDialogBox.CurSignalLabel.Value = app.signal_label;
            end
            
            % Create the basic signal :(Deafult parameters values):
            [app.signal,app.noisy_signal] = specifyModel(app);
            
            if ~t1_t2_diff_legal(app.t1.Value,app.t2.Value,app.Tcycle,app.signal_label)
                if  ~app.DBDialogBoxExist 
                    app.TimeDiff.Visible = 'on';
                    app.TimesTable.Visible = 'on';
                else
                    if strcmp(app.DBDialogBox.TabGroup.SelectedTab.Title,'Current GUI`s signal')
                       app.TimeDiff.Visible = 'on';
                       app.TimesTable.Visible = 'on';
                    else
                        app.TimeDiff.Visible = 'off';
                        app.TimesTable.Visible = 'off';
                    end
                end
            else
                app.TimeDiff.Visible = 'off';
                app.TimesTable.Visible = 'off';
                PlotSignal(app);
            end
        end

        % AlphaSlider value changed function
        function AlphaSliderValueChanged(app)
            [app.signal,app.noisy_signal] = specifyModel(app);
            
            if t1_t2_diff_legal(app.t1.Value,app.t2.Value,app.Tcycle,app.signal_label)
                app.TimeDiff.Visible = 'off';
                app.TimesTable.Visible = 'off';
                PlotSignal(app);
            else
                app.TimeDiff.Visible = 'on';
                app.TimesTable.Visible = 'on';
            end
        end

        % BetaSlider value changed function
        function BetaSliderValueChanged(app)
            [app.signal,app.noisy_signal] = specifyModel(app);
            
            if t1_t2_diff_legal(app.t1.Value,app.t2.Value,app.Tcycle,app.signal_label)
                app.TimeDiff.Visible = 'off';
                app.TimesTable.Visible = 'off';
                PlotSignal(app);
            else
                app.TimeDiff.Visible = 'on';
                app.TimesTable.Visible = 'on';
            end
        end

        % PlotSignalButton button pushed function
        function PlotSignal(app)
            if app.DBDialogBoxExist && ...
                    ~strcmp(app.DBDialogBox.TabGroup.SelectedTab.Title,'Current GUI`s signal')
                plot(app.UIAxes,1);
                return
            end
            if strcmp(app.Noise.SelectedObject.Text,'AWGN')
                plot(app.UIAxes,app.t*1E+3,app.noisy_signal); %miliseconds
                ylim(app.UIAxes,[-2 2]);%for low SNR: ylim(app.UIAxes,[-5 5]);
            else
                plot(app.UIAxes,app.t*1E+3,app.signal); %miliseconds
                %plot(app.UIAxes,app.t,app.signal); %seconds
                ylim(app.UIAxes,[-2 2]);
            end
            %axis([0 10*pi -1.5 1.5]);% TODO : let the user set boundaries?
            app.UIAxes.Title.String = app.signal_label;
            xlim(app.UIAxes,[0 200]);%milisec
            
            
            %TODO : zoom-in option ...
        end

        % Alpha3Slider value changed function
        function Alpha3SliderValueChanged(app)
            [app.signal,app.noisy_signal] = specifyModel(app);
            
            if t1_t2_diff_legal(app.t1.Value,app.t2.Value,app.Tcycle,app.signal_label)
                app.TimeDiff.Visible = 'off';
                app.TimesTable.Visible = 'off';
                PlotSignal(app);
            else
                app.TimeDiff.Visible = 'on';
                app.TimesTable.Visible = 'on';
            end
        end

        % Alpha5Slider value changed function
        function Alpha5SliderValueChanged(app)
            [app.signal,app.noisy_signal] = specifyModel(app);
            
            if t1_t2_diff_legal(app.t1.Value,app.t2.Value,app.Tcycle,app.signal_label)
                app.TimeDiff.Visible = 'off';
                app.TimesTable.Visible = 'off';
                PlotSignal(app);
            else
                app.TimeDiff.Visible = 'on';
                app.TimesTable.Visible = 'on';
            end
        end

        % Alpha7Slider value changed function
        function Alpha7SliderValueChanged(app)
            [app.signal,app.noisy_signal] = specifyModel(app);
            
            if t1_t2_diff_legal(app.t1.Value,app.t2.Value,app.Tcycle,app.signal_label)
                app.TimeDiff.Visible = 'off';
                app.TimesTable.Visible = 'off';
                PlotSignal(app);
            else
                app.TimeDiff.Visible = 'on';
                app.TimesTable.Visible = 'on';
            end
        end

        % Frequency selection change function
        function FreqSelectionChanged(app, event)
            switch app.Frequency.SelectedObject
                case app.f50
                    app.f = 50;
                case app.f60
                    app.f = 60;
            end
            
            [app.signal,app.noisy_signal] = specifyModel(app);
            
            if t1_t2_diff_legal(app.t1.Value,app.t2.Value,app.Tcycle,app.signal_label)
                app.TimeDiff.Visible = 'off';
                app.TimesTable.Visible = 'off';
                PlotSignal(app);
            else
                app.TimeDiff.Visible = 'on';
                app.TimesTable.Visible = 'on';
            end
        end

        % NumOfCycles value changed function
        function NumOfCyclesValueChanged(app)
            
            app.NumofTcycles = app.NumOfCycles.Value;
            
            app.t_end = app.NumofTcycles*app.Tcycle;
            
            app.t = [app.t_start : app.dt : app.t_end ];
            app.t = app.t(1:end-1);
            [app.signal,app.noisy_signal]=specifyModel(app);
            
            PlotSignal(app);
            
        end

        % t1 value changed function
        function t1ValueChanged(app)
            
            [app.signal] = specifyModel(app);
            
            if t1_t2_diff_legal(app.t1.Value,app.t2.Value,app.Tcycle,app.signal_label)
                app.TimeDiff.Visible = 'off';
                app.TimesTable.Visible = 'off';
                PlotSignal(app);
            else
                app.TimeDiff.Visible = 'on';
                app.TimesTable.Visible = 'on';
                %pop-up msg
            end
        end

        % t2 value changed function
        function t2ValueChanged(app)

            [app.signal,app.noisy_signal] = specifyModel(app);
            
            if t1_t2_diff_legal(app.t1.Value,app.t2.Value,app.Tcycle,app.signal_label)
                app.TimeDiff.Visible = 'off';
                app.TimesTable.Visible = 'off';
                PlotSignal(app);
            else
                app.TimeDiff.Visible = 'on';
                app.TimesTable.Visible = 'on';
                %pop-up msg
            end
        end

        % TauSlider value changed function
        function TauSliderValueChanged(app)
            value = app.TauSlider.Value;
            app.tau = value./1000 ;% ms to sec
            
            [app.signal,app.noisy_signal] = specifyModel(app);
            
            if t1_t2_diff_legal(app.t1.Value,app.t2,app.Tcycle,app.signal_label)
                app.TimeDiff.Visible = 'off';
                app.TimesTable.Visible = 'off';
                PlotSignal(app);
            else
                app.TimeDiff.Visible = 'on';
                app.TimesTable.Visible = 'on';
            end
            
        end

        % FnSlider value changed function
        function FnSliderValueChanged(app)
            app.Fn = app.FnSlider.Value;
            
            [app.signal,app.noisy_signal] = specifyModel(app);
            
            if t1_t2_diff_legal(app.t1.Value,app.t2,app.Tcycle,app.signal_label)
                PlotSignal(app);
            end        
        end

        % KSlider value changed function
        function KSliderValueChanged(app)
            app.k = app.KSlider.Value;
            
            [app.signal,app.noisy_signal] = specifyModel(app);
            
            if t1_t2_diff_legal(app.t1.Value,app.t2,app.Tcycle,app.signal_label)
                app.TimeDiff.Visible = 'off';
                app.TimesTable.Visible = 'off';
                PlotSignal(app);
            else
                app.TimeDiff.Visible = 'on';
                app.TimesTable.Visible = 'on';
            end          
        end

        % AlphaFlickerSlider value changed function
        function AlphaFlickerSliderValueChanged(app)
            
            app.alpha_flicker = app.AlphaFlickerSlider.Value;
            
            [app.signal,app.noisy_signal] = specifyModel(app);
            
            if t1_t2_diff_legal(app.t1.Value,app.t2,app.Tcycle,app.signal_label)
                app.TimeDiff.Visible = 'off';
                app.TimesTable.Visible = 'off';
                PlotSignal(app);
            else
                app.TimeDiff.Visible = 'on';
                app.TimesTable.Visible = 'on';
            end           
        end

        % NoiseSlider value changed function
        function NoiseSliderValueChanged(app)
            SNRinDb = app.NoiseSlider.Value;
            
            app.noisy_signal = awgn(app.signal,SNRinDb);
            
            PlotSignal(app);
            
        end

        % Noise selection change function
        function NoiseSelectionChanged(app, event)
            if strcmp(app.Noise.SelectedObject.Text,'AWGN')
                app.NoiseSlider.Enable = 'on';
                app.NoiseSliderLabel.Enable = 'on';
                PlotSignal(app);
            else
                app.NoiseSlider.Enable = 'off';
                app.NoiseSliderLabel.Enable = 'off';
                PlotSignal(app);
            end
        end

        % CreateDataBase button pushed function
        function CreateDBButtonPushed(app)
            % Disable GUI button options button while dialog is open
            app.DBDialogBoxExist =1;
            app.DBDialogBox = DataBaseCreator(); % Create the DB dialbox
            app.DBDialogBox.SetUpfunc(app); % Let DB dialbox access to mainAPP data
            % openvar('DataBaseCreator.DataBase');
            %export(app)
        end

        % FigureGUI close request function
        function FigureGUICloseRequest(app)
            close all force;
            delete(app);
        end

        % TimesTable button pushed function
        function TimesTableButtonPushed(app)
           imshow('TimesTable.JPG');
        end

        % Switch value changed function
        function StartPlotting(app)
            %
            %             PlotOrNot = app.Switch.Value ;
            %             if strcmp(PlotOrNot,'On')
            %                 type = app.ListBox.Value;
            %                 [t,y,y_title] = choose_model_power_quality_signal_labelances(type);
            %             end
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FigureGUI = uifigure;
            app.FigureGUI.Name = 'UI Figure';
            app.FigureGUI.CloseRequestFcn = createCallbackFcn(app, @FigureGUICloseRequest);
            setAutoResize(app, app.FigureGUI, true)
            app.UIAxes = uiaxes(app.FigureGUI);
            title(app.UIAxes, 'Title');
            xlabel(app.UIAxes, 'time [ms]');
            ylabel(app.UIAxes, 'V [pu]');
            app.LabelListBox = uilabel(app.FigureGUI);
            app.LabelListBox.Text = 'Disturbs List';
            app.ListBox = uilistbox(app.FigureGUI);
            app.ListBox.Items = {'Normal', 'Sag', 'Swell', 'Interruption', 'Harmonics', 'Oscillatory transient', 'Sag+Harmonics', 'Swell+Harmonics', 'Flicker', 'Notch', 'Spike', 'Flicker+Harmonics', 'Interruption+Harmonics', 'Flicker+Sag', 'Flicker+Swell', 'Impulsive Transient'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @DisListBox);
            app.ListBox.Value = 'Normal';
            app.AlphaSliderLabel = uilabel(app.FigureGUI);
            app.AlphaSliderLabel.Text = 'α';
            app.AlphaSlider = uislider(app.FigureGUI);
            app.AlphaSlider.Limits = [0 1];
            app.AlphaSlider.ValueChangedFcn = createCallbackFcn(app, @AlphaSliderValueChanged);
            app.BetaSliderLabel = uilabel(app.FigureGUI);
            app.BetaSliderLabel.Text = 'β';
            app.BetaSlider = uislider(app.FigureGUI);
            app.BetaSlider.ValueChangedFcn = createCallbackFcn(app, @BetaSliderValueChanged);
            app.Alpha3SliderLabel = uilabel(app.FigureGUI);
            app.Alpha3SliderLabel.Text = 'α3';
            app.Alpha3Slider = uislider(app.FigureGUI);
            app.Alpha3Slider.Limits = [0.05 0.15];
            app.Alpha3Slider.ValueChangedFcn = createCallbackFcn(app, @Alpha3SliderValueChanged);
            app.Alpha3Slider.Value = 0.05;
            app.Alpha5SliderLabel = uilabel(app.FigureGUI);
            app.Alpha5SliderLabel.Text = 'α5';
            app.Alpha5Slider = uislider(app.FigureGUI);
            app.Alpha5Slider.Limits = [0.05 0.15];
            app.Alpha5Slider.ValueChangedFcn = createCallbackFcn(app, @Alpha5SliderValueChanged);
            app.Alpha5Slider.Value = 0.05;
            app.Alpha7SliderLabel = uilabel(app.FigureGUI);
            app.Alpha7SliderLabel.Text = 'α7';
            app.Alpha7Slider = uislider(app.FigureGUI);
            app.Alpha7Slider.Limits = [0.05 0.15];
            app.Alpha7Slider.ValueChangedFcn = createCallbackFcn(app, @Alpha7SliderValueChanged);
            app.Alpha7Slider.Value = 0.05;
            app.t1Label = uilabel(app.FigureGUI);
            app.t1Label.Text = 't1';
            app.t1 = uieditfield(app.FigureGUI, 'numeric');
            app.t1.ValueChangedFcn = createCallbackFcn(app, @t1ValueChanged);
            app.t1.Limits = [0 1000];
            app.t2Label = uilabel(app.FigureGUI);
            app.t2Label.Text = 't2';
            app.t2 = uieditfield(app.FigureGUI, 'numeric');
            app.t2.ValueChangedFcn = createCallbackFcn(app, @t2ValueChanged);
            app.t2.Limits = [0 1000];
            app.NumOfCyclesLabel = uilabel(app.FigureGUI);
            app.NumOfCyclesLabel.Text = {'Number'; ' of'; ' Cycles'};
            app.NumOfCycles = uieditfield(app.FigureGUI, 'numeric');
            app.NumOfCycles.ValueChangedFcn = createCallbackFcn(app, @NumOfCyclesValueChanged);
            app.NumOfCycles.Limits = [1 50];
            app.NumOfCycles.Value = 10;
            app.Frequency = uibuttongroup(app.FigureGUI);
            app.Frequency.SelectionChangedFcn = createCallbackFcn(app, @FreqSelectionChanged, true);
            app.Frequency.Title = 'Frequency';
            app.Frequency.Units = 'pixels';
            app.f50 = uiradiobutton(app.Frequency);
            app.f50.Text = '50 Hz';
            app.f50.Value = true;
            app.f60 = uiradiobutton(app.Frequency);
            app.f60.Text = '60 Hz';
            app.TimeDiff = uitextarea(app.FigureGUI);
            app.TimeDiff.Value = {'The chosen time difference is not legal '};
            app.TauSliderLabel = uilabel(app.FigureGUI);
            app.TauSliderLabel.Text = 'τ';
            app.TauSlider = uislider(app.FigureGUI);
            app.TauSlider.Limits = [8 40];
            app.TauSlider.ValueChangedFcn = createCallbackFcn(app, @TauSliderValueChanged);
            app.TauSlider.Value = 8;
            app.FnSliderLabel = uilabel(app.FigureGUI);
            app.FnSliderLabel.Text = 'Fn';
            app.FnSlider = uislider(app.FigureGUI);
            app.FnSlider.Limits = [300 900];
            app.FnSlider.ValueChangedFcn = createCallbackFcn(app, @FnSliderValueChanged);
            app.FnSlider.Value = 300;
            app.KSliderLabel = uilabel(app.FigureGUI);
            app.KSliderLabel.Text = 'K';
            app.KSlider = uislider(app.FigureGUI);
            app.KSlider.Limits = [0.1 0.4];
            app.KSlider.ValueChangedFcn = createCallbackFcn(app, @KSliderValueChanged);
            app.KSlider.Value = 0.4;
            app.AlphaFlickerSliderLabel = uilabel(app.FigureGUI);
            app.AlphaFlickerSliderLabel.Text = {'αf'; ''};
            app.AlphaFlickerSlider = uislider(app.FigureGUI);
            app.AlphaFlickerSlider.Limits = [0.1 0.2];
            app.AlphaFlickerSlider.ValueChangedFcn = createCallbackFcn(app, @AlphaFlickerSliderValueChanged);
            app.AlphaFlickerSlider.Value = 0.1;
            app.NoiseSliderLabel = uilabel(app.FigureGUI);
            app.NoiseSliderLabel.Enable = 'off';
            app.NoiseSliderLabel.Text = 'SNR';
            app.NoiseSlider = uislider(app.FigureGUI);
            app.NoiseSlider.Limits = [20 50];
            app.NoiseSlider.MajorTickLabels = {'20', '25', '30', '35', '40', '45', '50db'};
            app.NoiseSlider.ValueChangedFcn = createCallbackFcn(app, @NoiseSliderValueChanged);
            app.NoiseSlider.MinorTicks = [];
            app.NoiseSlider.Enable = 'off';
            app.NoiseSlider.Value = 50;
            app.Noise = uibuttongroup(app.FigureGUI);
            app.Noise.SelectionChangedFcn = createCallbackFcn(app, @NoiseSelectionChanged, true);
            app.Noise.Title = 'Noise';
            app.Noise.Units = 'pixels';
            app.NoNoise = uiradiobutton(app.Noise);
            app.NoNoise.Text = 'No noise';
            app.NoNoise.Value = true;
            app.AWGN = uiradiobutton(app.Noise);
            app.AWGN.Text = 'AWGN';
            app.CreateDataBase = uibutton(app.FigureGUI, 'push');
            app.CreateDataBase.ButtonPushedFcn = createCallbackFcn(app, @CreateDBButtonPushed);
            app.CreateDataBase.Text = {'CREATE'; ' DATABASE'};
            app.TimesTable = uibutton(app.FigureGUI, 'push');
            app.TimesTable.ButtonPushedFcn = createCallbackFcn(app, @TimesTableButtonPushed);
            app.TimesTable.Text = 'Times Table Reminder';
            app.LabelTextArea = uilabel(app.FigureGUI);
            app.LabelTextArea.Text = 'Text Area';
            app.Cover1 = uitextarea(app.FigureGUI);
        end
    end

    methods (Access = public)

        % Construct app
        function app = App()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FigureGUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FigureGUI)
        end
    end
end