classdef DataBaseCreator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DBCreator            matlab.ui.Figure                   % Data Base...
        Label                matlab.ui.control.Label            % Export si...
        TabGroup             matlab.ui.container.TabGroup       % Current G...
        TabCurrent           matlab.ui.container.Tab            % Current G...
        AddCurGUISignal      matlab.ui.control.Button           % Add
        NumOfVecCurrentLabel matlab.ui.control.Label            % Number of...
        NumOfVecCurrent      matlab.ui.control.NumericEditField % [-Inf Inf]
        CurSignalLabel       matlab.ui.control.TextArea        
        TabSpecific          matlab.ui.container.Tab            % A Specifi...
        ExportSpecific       matlab.ui.control.Button           % Add
        NoiseSpecific        matlab.ui.container.ButtonGroup    % Noise
        NoNoise              matlab.ui.control.RadioButton      % No noise
        SNR_set              matlab.ui.control.RadioButton      % AWGN (ran...
        SNR_rand             matlab.ui.control.RadioButton      % AWGN (set...
        FreqSpecific         matlab.ui.container.ButtonGroup    % Frequency
        f_50                 matlab.ui.control.RadioButton      % 50 Hz
        f_60                 matlab.ui.control.RadioButton      % 60 Hz
        f_Rand               matlab.ui.control.RadioButton      % Random
        NumOfCyclesSpecific  matlab.ui.container.ButtonGroup    % Number ot...
        NumOfCycles_Ten      matlab.ui.control.RadioButton      % 10
        NumOfCycles_Rand     matlab.ui.control.RadioButton      % Random
        NoiseSliderLabel     matlab.ui.control.Label            % SNR
        SNRsliderSpecific    matlab.ui.control.Slider           % [20 50]
        Label2               matlab.ui.control.Label            % Number of...
        NumOfVecSpecific     matlab.ui.control.NumericEditField % [0 100000]
        SpecificSignalLabel  matlab.ui.control.TextArea        
        TabAll               matlab.ui.container.Tab            % All Disturbs
        ExportAll            matlab.ui.control.Button           % Export 
        NumOfVecAllLabel     matlab.ui.control.Label            % Total num...
        NumOfVecAll          matlab.ui.control.NumericEditField % [0 1000000]
        NoiseAll             matlab.ui.container.ButtonGroup    % Noise
        NoNoise_all          matlab.ui.control.RadioButton      % No noise
        SNR_rand_all         matlab.ui.control.RadioButton      % AWGN (ran...
        SNR_set_all          matlab.ui.control.RadioButton      % AWGN (set...
        FreqAll              matlab.ui.container.ButtonGroup    % Frequency
        f_50_all             matlab.ui.control.RadioButton      % 50 Hz
        f_60_all             matlab.ui.control.RadioButton      % 60 Hz
        f_rand_all           matlab.ui.control.RadioButton      % Random
        NumOfCyclesAll       matlab.ui.container.ButtonGroup    % Number ot...
        NumOfCycles_Ten_all  matlab.ui.control.RadioButton      % 10
        NumOfCycles_rand_all matlab.ui.control.RadioButton      % Random
        SNRsliderAllLabel    matlab.ui.control.Label            % SNR
        SNRsliderAll         matlab.ui.control.Slider           % [20 50]
        ProgressLabel        matlab.ui.control.Label            % Progress
        Progress             matlab.ui.control.EditField       
    end

    
    properties (Access = private)
        CallingApp; % Main app object
        signal;
        noisy_signal;
        
    end
    
    properties (Access = public)
        DataBase; % Description
        field1 = 'labels';
        field2 = 'signals';
        file;
        path;
        signal_label;
    end
    
    methods (Access = public)
        
        function SetUpfunc(app,otherApp)
            app.CallingApp = otherApp ;
            app.CurSignalLabel.Value = app.CallingApp.signal_label;
            app.SpecificSignalLabel.Value = app.CallingApp.signal_label;
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DBCreator.Name ='Data Base Creator';
            
            app.DataBase = struct(app.field1,[],app.field2,[]);% = {};
            
            %app.signal_label = app.CallingApp.signal_label;
            %app.signal = app.CallingApp.signal;
            
            %  app.CallingApp = App.mlapp;


            [app.file,app.path] = uiputfile('*.mat','Save DataBase file at:');
            %app.file = 'tryyy.mat';
            
            app.Progress.Visible = 'off';
            app.ProgressLabel.Visible = 'off';
            
        end

        % AddCurGUISignal button pushed function
        function ExportCurGUISignalButtonPushed(app)
            % Add signals to DB
            for i=1:app.NumOfVecCurrent.Value
                if isempty(app.DataBase(end).labels)
                    app.DataBase(1).labels = app.CallingApp.signal_label;
                    app.DataBase(1).signals = app.CallingApp.signal;
                else
                    app.DataBase(end+1).labels = app.CallingApp.signal_label;
                    app.DataBase(end).signals = app.CallingApp.signal;
                end
            end
            
            %specifyModel4DB(app.CallingApp);
            %  app.CallingApp.
            SignalsDataBase = app.DataBase;
            save(app.file,'SignalsDataBase');
            %    delete(app);
        end

        % ExportSpecific button pushed function
        function ExportSpecificButtonPushed(app)
            %             % Add signals to DB
            specifyModel4DB(app.CallingApp,app,1);
            
            SignalsDataBase = app.DataBase;
            save(app.file,'SignalsDataBase');
        end

        % Noise selection change function
        function NoiseSpecificSelectionChanged(app, event)
            if strcmp(app.NoiseSpecific.SelectedObject.Text,'AWGN (set SNR)')
                app.SNRsliderSpecific.Enable = 'on';
                app.NoiseSliderLabel.Enable = 'on';
            else
                app.SNRsliderSpecific.Enable = 'off';
                app.NoiseSliderLabel.Enable = 'off';
            end
        end

        % DBCreator close request function
        function DBCreatorCloseRequest(app)
            app.CallingApp.CreateDataBase.Visible='on';
            app.CallingApp.Cover1.Visible = 'off';
            app.CallingApp.DBDialogBoxExist = 0;
            
            delete(app);
            
        end

        % ExportAll button pushed function
        function ExportAllButtonPushed(app)
            
            for i=1:length(app.CallingApp.ListBox.Items)
                defaultOffVisibality(app.CallingApp);
                app.signal_label = app.CallingApp.ListBox.Items{i};
                app.CallingApp.signal_label = app.signal_label; 
                SetParametersRange( app.CallingApp );
                specifyModel4DB(app.CallingApp,app,i);
            end
            
            SignalsDataBase = app.DataBase;
            save(app.file,'SignalsDataBase');
        end

        % NumOfVecSpecific value changed function
        function NumOfVecSpecificValueChanged(app)
            value = app.NumOfVecSpecific.Value;
            
        end

        % TabGroup selection changed function
        function TabGroupSelectionChanged(app)
            selectedTab = app.TabGroup.SelectedTab;
            app.CallingApp.CreateDataBase.Visible='off';
            
            switch app.TabGroup.SelectedTab.Title
                case 'Current GUI`s signal'
                    %SlidersVisibilty( app.CallingApp );
                    app.CallingApp.Frequency.Visible = 'on';
                    app.CallingApp.Noise.Visible ='on';
                    app.CallingApp.NumOfCycles.Visible = 'on';
                    app.CallingApp.NumOfCyclesLabel.Visible = 'on';
                    app.CurSignalLabel.Visible = 'on';
                    app.CallingApp.UIAxes.Visible='on';
                    
                case {'A Specific Disturb' , 'All Disturbs'}
                    %defaultOffVisibality(app.CallingApp);
                    %                                %           app.CallingApp.FigureGUI.Position([3,4])-[180,0];
                    app.SpecificSignalLabel.Visible = 'on';
                    app.CallingApp.Frequency.Visible = 'off';
                    app.CallingApp.Noise.Visible ='off';
                    app.CallingApp.NumOfCycles.Visible = 'off';
                    app.CallingApp.NumOfCyclesLabel.Visible = 'off';
                    app.CallingApp.NoiseSlider.Visible = 'off';
                    app.CallingApp.NoiseSliderLabel.Visible = 'off';
                    app.CallingApp.TimesTable.Visible = 'off';
                    app.CallingApp.TimeDiff.Visible = 'off';
                    app.CallingApp.UIAxes.Visible='on';% TODO - on?
                    app.SpecificSignalLabel.Visible = 'on';
                    plot(app.CallingApp.UIAxes,1)
                    app.CallingApp.UIAxes.Visible='off';
                    app.CallingApp.Cover1.Visible = 'on';
                    %app.CallingApp.FigureGUI.Position([3,4])= app.CallingApp.ListBox.Position([3,4]);
                    %app.CallingApp.ListBox.Position([1,2]) = [0,0];
                    
                    if strcmp(app.TabGroup.SelectedTab.Title,'All Disturbs')
                        %app.CallingApp.UIAxes.Visible='off';
                        app.SpecificSignalLabel.Visible = 'off';
                        app.CurSignalLabel.Visible = 'off';
                    end
                    
            end %switch
        end

        % NoiseAll selection change function
        function NoiseAllSelectionChanged(app, event)
            if strcmp(app.NoiseAll.SelectedObject.Text,'AWGN (set SNR)')
                app.SNRsliderAll.Enable = 'on';
                app.SNRsliderAllLabel.Enable = 'on';
            else
                app.SNRsliderAll.Enable = 'off';
                app.SNRsliderAllLabel.Enable = 'off';
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DBCreator = uifigure;
            app.DBCreator.Name = 'Data Base Creator';
            app.DBCreator.CloseRequestFcn = createCallbackFcn(app, @DBCreatorCloseRequest);
            setAutoResize(app, app.DBCreator, true)
            app.Label = uilabel(app.DBCreator);
            app.Label.Text = 'Export signals to your DataBase';
            app.TabGroup = uitabgroup(app.DBCreator);
            app.TabGroup.Units = 'pixels';
            app.TabCurrent = uitab(app.TabGroup);
            app.TabCurrent.Units = 'pixels';
            app.TabCurrent.Title = 'Current GUI`s signal';
            app.AddCurGUISignal = uibutton(app.TabCurrent, 'push');
            app.AddCurGUISignal.ButtonPushedFcn = createCallbackFcn(app, @ExportCurGUISignalButtonPushed);
            app.AddCurGUISignal.Text = {'Add'; 'current '; 'GUI''s signal '};
            app.NumOfVecCurrentLabel = uilabel(app.TabCurrent);
            app.NumOfVecCurrentLabel.Text = 'Number of vectors to export';
            app.NumOfVecCurrent = uieditfield(app.TabCurrent, 'numeric');
            app.CurSignalLabel = uitextarea(app.TabCurrent);
            app.CurSignalLabel.Editable = 'off';
            app.TabSpecific = uitab(app.TabGroup);
            app.TabSpecific.Units = 'pixels';
            app.TabSpecific.Title = 'A Specific Disturb';
            app.ExportSpecific = uibutton(app.TabSpecific, 'push');
            app.ExportSpecific.ButtonPushedFcn = createCallbackFcn(app, @ExportSpecificButtonPushed);
            app.ExportSpecific.Text = {'Add'; 'the chosen disturb'};
            app.NoiseSpecific = uibuttongroup(app.TabSpecific);
            app.NoiseSpecific.SelectionChangedFcn = createCallbackFcn(app, @NoiseSpecificSelectionChanged, true);
            app.NoiseSpecific.Title = 'Noise';
            app.NoiseSpecific.Units = 'pixels';
            app.NoNoise = uiradiobutton(app.NoiseSpecific);
            app.NoNoise.Text = 'No noise';
            app.NoNoise.Value = true;
            app.SNR_set = uiradiobutton(app.NoiseSpecific);
            app.SNR_set.Text = 'AWGN (random SNR)';
            app.SNR_rand = uiradiobutton(app.NoiseSpecific);
            app.SNR_rand.Text = 'AWGN (set SNR)';
            app.FreqSpecific = uibuttongroup(app.TabSpecific);
            app.FreqSpecific.Title = 'Frequency';
            app.FreqSpecific.Units = 'pixels';
            app.f_50 = uiradiobutton(app.FreqSpecific);
            app.f_50.Text = '50 Hz';
            app.f_50.Value = true;
            app.f_60 = uiradiobutton(app.FreqSpecific);
            app.f_60.Text = '60 Hz';
            app.f_Rand = uiradiobutton(app.FreqSpecific);
            app.f_Rand.Text = 'Random';
            app.NumOfCyclesSpecific = uibuttongroup(app.TabSpecific);
            app.NumOfCyclesSpecific.Title = 'Number ot Tcycle';
            app.NumOfCyclesSpecific.Units = 'pixels';
            app.NumOfCycles_Ten = uiradiobutton(app.NumOfCyclesSpecific);
            app.NumOfCycles_Ten.Text = '10';
            app.NumOfCycles_Ten.Value = true;
            app.NumOfCycles_Rand = uiradiobutton(app.NumOfCyclesSpecific);
            app.NumOfCycles_Rand.Text = 'Random';
            app.NoiseSliderLabel = uilabel(app.TabSpecific);
            app.NoiseSliderLabel.Enable = 'off';
            app.NoiseSliderLabel.Text = 'SNR';
            app.SNRsliderSpecific = uislider(app.TabSpecific);
            app.SNRsliderSpecific.Limits = [20 50];
            app.SNRsliderSpecific.MajorTickLabels = {'20', '25', '30', '35', '40', '45', '50db'};
            app.SNRsliderSpecific.MinorTicks = [];
            app.SNRsliderSpecific.Enable = 'off';
            app.SNRsliderSpecific.Value = 50;
            app.Label2 = uilabel(app.TabSpecific);
            app.Label2.Text = 'Number of  Vectors to add';
            app.NumOfVecSpecific = uieditfield(app.TabSpecific, 'numeric');
            app.NumOfVecSpecific.ValueChangedFcn = createCallbackFcn(app, @NumOfVecSpecificValueChanged);
            app.NumOfVecSpecific.Limits = [0 100000];
            app.SpecificSignalLabel = uitextarea(app.TabSpecific);
            app.SpecificSignalLabel.Editable = 'off';
            app.TabAll = uitab(app.TabGroup);
            app.TabAll.Units = 'pixels';
            app.TabAll.Title = 'All Disturbs';
            app.ExportAll = uibutton(app.TabAll, 'push');
            app.ExportAll.ButtonPushedFcn = createCallbackFcn(app, @ExportAllButtonPushed);
            app.ExportAll.Text = {'Export '; 'all listed disturbs'; '(random parameters)'};
            app.NumOfVecAllLabel = uilabel(app.TabAll);
            app.NumOfVecAllLabel.Text = 'Total number of vectors in DB';
            app.NumOfVecAll = uieditfield(app.TabAll, 'numeric');
            app.NumOfVecAll.Limits = [0 1000000];
            app.NumOfVecAll.ValueDisplayFormat = '%.0f';
            app.NoiseAll = uibuttongroup(app.TabAll);
            app.NoiseAll.SelectionChangedFcn = createCallbackFcn(app, @NoiseAllSelectionChanged, true);
            app.NoiseAll.Title = 'Noise';
            app.NoiseAll.Units = 'pixels';
            app.NoNoise_all = uiradiobutton(app.NoiseAll);
            app.NoNoise_all.Text = 'No noise';
            app.NoNoise_all.Value = true;
            app.SNR_rand_all = uiradiobutton(app.NoiseAll);
            app.SNR_rand_all.Text = 'AWGN (random SNR)';
            app.SNR_set_all = uiradiobutton(app.NoiseAll);
            app.SNR_set_all.Text = 'AWGN (set SNR)';
            app.FreqAll = uibuttongroup(app.TabAll);
            app.FreqAll.Title = 'Frequency';
            app.FreqAll.Units = 'pixels';
            app.f_50_all = uiradiobutton(app.FreqAll);
            app.f_50_all.Text = '50 Hz';
            app.f_50_all.Value = true;
            app.f_60_all = uiradiobutton(app.FreqAll);
            app.f_60_all.Text = '60 Hz';
            app.f_rand_all = uiradiobutton(app.FreqAll);
            app.f_rand_all.Text = 'Random';
            app.NumOfCyclesAll = uibuttongroup(app.TabAll);
            app.NumOfCyclesAll.Title = 'Number ot Tcycle';
            app.NumOfCyclesAll.Units = 'pixels';
            app.NumOfCycles_Ten_all = uiradiobutton(app.NumOfCyclesAll);
            app.NumOfCycles_Ten_all.Text = '10';
            app.NumOfCycles_Ten_all.Value = true;
            app.NumOfCycles_rand_all = uiradiobutton(app.NumOfCyclesAll);
            app.NumOfCycles_rand_all.Text = 'Random';
            app.SNRsliderAllLabel = uilabel(app.TabAll);
            app.SNRsliderAllLabel.Enable = 'off';
            app.SNRsliderAllLabel.Text = 'SNR';
            app.SNRsliderAll = uislider(app.TabAll);
            app.SNRsliderAll.Limits = [20 50];
            app.SNRsliderAll.MajorTickLabels = {'20', '25', '30', '35', '40', '45', '50db'};
            app.SNRsliderAll.MinorTicks = [];
            app.SNRsliderAll.Enable = 'off';
            app.SNRsliderAll.Value = 50;
            app.ProgressLabel = uilabel(app.TabAll);
            app.ProgressLabel.Text = 'Progress';
            app.Progress = uieditfield(app.TabAll, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = DataBaseCreator()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DBCreator)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DBCreator)
        end
    end
end